classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CorrientedelpanelLabel         matlab.ui.control.Label
        SimularyGraficarButton         matlab.ui.control.StateButton
        AngulodedesfaseEditField       matlab.ui.control.NumericEditField
        AngulodedesfaseEditFieldLabel  matlab.ui.control.Label
        PISwitch                       matlab.ui.control.Switch
        PISwitchLabel                  matlab.ui.control.Label
        FrecuenciadeconmutacinEditField  matlab.ui.control.NumericEditField
        FrecuenciadeconmutacinEditFieldLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SimularyGraficarButton
        function SimularyGraficarButtonValueChanged(app, event)
% --- NUEVO: BLOQUEAR EL BOTÓN AL INICIO ---
app.SimularyGraficarButton.Enable = 'off';
app.SimularyGraficarButton.Text = 'Simulando...';
drawnow; % Fuerza a la pantalla a mostrar el botón bloqueado inmediatamente

% 1. LEER LOS DATOS DE LA INTERFAZ
frecuencia = app.FrecuenciadeconmutacinEditField.Value;
fase_grados = app.AngulodedesfaseEditField.Value; 

fase_rad = fase_grados * (pi / 180);

if strcmp(app.PISwitch.Value, 'On')
    modo = 1; 
else
    modo = 0; 
end

% 2. ENVIAR VARIABLES AL WORKSPACE
assignin('base', 'Fs', frecuencia);
assignin('base', 'fase_ref', fase_rad); 
assignin('base', 'modo_manual', modo);

try
    % 3. EJECUTAR LA SIMULACIÓN
    out = sim('PARCIAL_POTENCIAII'); 
    
    % 4. EXTRACCIÓN INDEPENDIENTE (Timeseries)
    t_vp = out.V_prim.Time;
    vp = out.V_prim.Data;
    
    t_vs = out.V_sec.Time;
    vs = out.V_sec.Data;
    
    t_il = out.I_L.Time;
    il = out.I_L.Data;
    
    t_vpv = out.V_pv.Time;
    vpv = out.V_pv.Data;
    
    t_vref = out.V_ref.Time;
    vref = out.V_ref.Data;
    
    % Mostrar corriente de entrada
    ipv = out.I_pv.Data;
    corriente_final = ipv(end);
    app.CorrientedelpanelLabel.Text = sprintf('Corriente Panel: %.2f A', corriente_final);

    % --- 5. GRAFICAR EN UIAxes (Arriba) ---
    plot(app.UIAxes, t_vp, vp, 'b', 'LineWidth', 1.5);
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, t_vs, vs, 'r', 'LineWidth', 1.5); 
    plot(app.UIAxes, t_il, il * 5, 'y', 'LineWidth', 1.5); 
    hold(app.UIAxes, 'off');
    title(app.UIAxes, 'Voltajes y Corriente Resonante');
    legend(app.UIAxes, {'V Primario', 'V Secundario', 'Corriente IL (x5)'});
    grid(app.UIAxes, 'on');
    
    periodo = 1 / frecuencia; 
    tiempo_final = t_vp(end); 
    xlim(app.UIAxes, [tiempo_final - (5 * periodo), tiempo_final]);

    % --- 6. GRAFICAR EN UIAxes2 (Abajo) ---
    plot(app.UIAxes2, t_vpv, vpv, 'g', 'LineWidth', 1.5);
    hold(app.UIAxes2, 'on');
    plot(app.UIAxes2, t_vref, vref, 'k--', 'LineWidth', 1.5);
    hold(app.UIAxes2, 'off');
    title(app.UIAxes2, 'Seguimiento MPPT (Vpv vs Vref)');
    legend(app.UIAxes2, {'Vpv (Panel)', 'Vref (Control)'}, 'Location', 'best'); 
    grid(app.UIAxes2, 'on');

    % --- NUEVO: DESBLOQUEAR EL BOTÓN AL TERMINAR CON ÉXITO ---
    app.SimularyGraficarButton.Enable = 'on';
    app.SimularyGraficarButton.Text = 'Simular y Graficar';

catch ME
    % --- NUEVO: DESBLOQUEAR EL BOTÓN SI OCURRE UN ERROR ---
    app.SimularyGraficarButton.Enable = 'on';
    app.SimularyGraficarButton.Text = 'Simular y Graficar';
    
    uialert(app.UIFigure, 'Ocurrió un error en la simulación.', 'Error');
    disp(ME.message);
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vprim, Vsec, IL')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Vpv vs Vref')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FrecuenciadeconmutacinEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciadeconmutacinEditFieldLabel.Text = 'Frecuencia de conmutación';
            app.FrecuenciadeconmutacinEditField = uieditfield(app.UIFigure, 'numeric');
            app.PISwitchLabel = uilabel(app.UIFigure);
            app.PISwitchLabel.Text = 'PI';
            app.PISwitch = uiswitch(app.UIFigure, 'slider');
            app.PISwitch.Items = {'On', 'Off'};
            app.PISwitch.Value = 'On';
            app.AngulodedesfaseEditFieldLabel = uilabel(app.UIFigure);
            app.AngulodedesfaseEditFieldLabel.Text = 'Angulo de desfase (°)';
            app.AngulodedesfaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimularyGraficarButton = uibutton(app.UIFigure, 'state');
            app.SimularyGraficarButton.ValueChangedFcn = createCallbackFcn(app, @SimularyGraficarButtonValueChanged, true);
            app.SimularyGraficarButton.Text = 'Simular y Graficar';
            app.CorrientedelpanelLabel = uilabel(app.UIFigure);
            app.CorrientedelpanelLabel.Text = 'Corriente del panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Associate the Simulink Model
            app.Simulation = simulation('PARCIAL_POTENCIAII');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end